classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ControlpannelTab             matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        LoadPanel_3                  matlab.ui.container.Panel
        GridPanel_6                  matlab.ui.container.Panel
        VoltageVKnob_10Label         matlab.ui.control.Label
        VrmsGrid                     matlab.ui.control.Knob
        VaLamp_10Label               matlab.ui.control.Label
        VaLamp_10                    matlab.ui.control.Lamp
        VbLamp_10Label               matlab.ui.control.Label
        VbLamp_10                    matlab.ui.control.Lamp
        VcLamp_10Label               matlab.ui.control.Label
        VcLamp_10                    matlab.ui.control.Lamp
        VEditField_10Label           matlab.ui.control.Label
        VEditField_10                matlab.ui.control.NumericEditField
        SequenceControlPanel         matlab.ui.container.Panel
        StartButton                  matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        MotorLoadPanel               matlab.ui.container.Panel
        LoadKnob                     matlab.ui.control.Knob
        LoadKnobLabel                matlab.ui.control.Label
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        UIAxes_5                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Image_2                      matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel_2   matlab.ui.control.Label
        SetParametersButton          matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        Panel                        matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        Panel2                       matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrequencySlider_2Label       matlab.ui.control.Label
        FrequencySlider_2            matlab.ui.control.Slider
        PolesSpinner_2Label          matlab.ui.control.Label
        PolesSpinner_2               matlab.ui.control.Spinner
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        HelpTextAreaLabel            matlab.ui.control.Label
        HelpTextArea                 matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function getModelValues(app)
            %vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            %app.VEditField_11.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\RTInductionMotorFeb10.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            %getModelValues(app);
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Laboratory1Button.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            app.SetParametersButton.Enable = 'off';        
            
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.XsEditField.Value);
            % Rr = num2str(app.RrEditField.Value); Plz add a textBox for Rr
            % and call it this way ^
            Lr = num2str(app.XrEditField.Value);
            Lm = num2str(app.XmEditField.Value);
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls);
            % set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            save_system('simulation\RTInductionMotorFeb10.slx');
            
        end

        % Value changing function: FrequencySlider_2
        function FrequencySlider_2ValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Freq'],'Value', freqSlider);
        end

        % Value changing function: PolesSpinner_2
        function PolesSpinner_2ValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.simulated = sim("simulation\RTInductionMotorFeb10.slx");
            
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                app.StartButton.Enable ='on';
            end
        end

        % Value changing function: VrmsGrid
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage'],'Value', voltKnob);
            % getModelValues(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.ControlpannelTab = uitab(app.TabGroup);
            app.ControlpannelTab.Title = 'Control pannel';
            app.GridLayout = uigridlayout(app.ControlpannelTab);
            app.LoadPanel_3 = uipanel(app.GridLayout);
            app.LoadPanel_3.Tooltip = {''};
            app.LoadPanel_3.Title = 'Load';
            app.LoadPanel_3.Tag = 'three states';
            app.GridPanel_6 = uipanel(app.GridLayout);
            app.GridPanel_6.Title = 'Grid';
            app.VoltageVKnob_10Label = uilabel(app.GridPanel_6);
            app.VoltageVKnob_10Label.Text = 'Voltage [V]';
            app.VrmsGrid = uiknob(app.GridPanel_6, 'continuous');
            app.VrmsGrid.Limits = [0 250];
            app.VrmsGrid.ValueChangingFcn = createCallbackFcn(app, @VrmsGridValueChanging, true);
            app.VaLamp_10Label = uilabel(app.GridPanel_6);
            app.VaLamp_10Label.Text = 'Va';
            app.VaLamp_10 = uilamp(app.GridPanel_6);
            app.VbLamp_10Label = uilabel(app.GridPanel_6);
            app.VbLamp_10Label.Text = 'Vb';
            app.VbLamp_10 = uilamp(app.GridPanel_6);
            app.VcLamp_10Label = uilabel(app.GridPanel_6);
            app.VcLamp_10Label.Text = 'Vc';
            app.VcLamp_10 = uilamp(app.GridPanel_6);
            app.VEditField_10Label = uilabel(app.GridPanel_6);
            app.VEditField_10Label.Text = '[V]';
            app.VEditField_10 = uieditfield(app.GridPanel_6, 'numeric');
            app.VEditField_10.Editable = 'off';
            app.VEditField_10.Tooltip = {'Voltage set by the knob'};
            app.SequenceControlPanel = uipanel(app.GridLayout);
            app.SequenceControlPanel.Title = 'Sequence Control';
            app.StartButton = uibutton(app.SequenceControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SequenceControlPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.MotorLoadPanel = uipanel(app.GridLayout);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.LoadKnob = uiknob(app.MotorLoadPanel, 'continuous');
            app.LoadKnob.Limits = [0 10000000];
            app.LoadKnobLabel = uilabel(app.MotorLoadPanel);
            app.LoadKnobLabel.Text = 'Load';
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.UIAxes_5 = uiaxes(app.GridLayout4);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes_4 = uiaxes(app.GridLayout4);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.GridLayout4);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel_2 = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel_2.Text = '3-PHASE INDUCTION MOTOR';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be change at any given moment during the lab experiment'};
            app.Panel = uipanel(app.GridLayout5);
            app.Panel.Title = 'Panel';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RsEditField.Value = 1;
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.Panel2 = uipanel(app.GridLayout5);
            app.Panel2.Title = 'Panel2';
            app.GridLayout7 = uigridlayout(app.Panel2);
            app.FrequencySlider_2Label = uilabel(app.GridLayout7);
            app.FrequencySlider_2Label.Text = 'Frequency ';
            app.FrequencySlider_2 = uislider(app.GridLayout7);
            app.FrequencySlider_2.ValueChangingFcn = createCallbackFcn(app, @FrequencySlider_2ValueChanging, true);
            app.PolesSpinner_2Label = uilabel(app.GridLayout7);
            app.PolesSpinner_2Label.Text = 'Poles';
            app.PolesSpinner_2 = uispinner(app.GridLayout7);
            app.PolesSpinner_2.ValueChangingFcn = createCallbackFcn(app, @PolesSpinner_2ValueChanging, true);
            app.PolesSpinner_2.Limits = [0 Inf];
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout9);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout9);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout9);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout9);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout9);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout9);
            app.PWMTextArea.Editable = 'off';
            app.HelpTextAreaLabel = uilabel(app.GridLayout9);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.GridLayout9);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'This option will allow you the student to contact administrators, or security in case of urgence.'};
            app.MotorTextArea_2Label = uilabel(app.GridLayout9);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout9);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end